classdef Interface_V_6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Inicializar             matlab.ui.control.Button
        StatusEditFieldLabel    matlab.ui.control.Label
        Status                  matlab.ui.control.EditField
        Capturar                matlab.ui.control.Button
        NomeImagemLabel         matlab.ui.control.Label
        NomeIm                  matlab.ui.control.EditField
        SelectCam               matlab.ui.container.ButtonGroup
        IPCamSelect             matlab.ui.control.RadioButton
        IntegradaSelect         matlab.ui.control.RadioButton
        Endereco                matlab.ui.control.EditField
        NomeVid                 matlab.ui.control.EditField
        Info                    matlab.ui.container.TabGroup
        PreviewTab              matlab.ui.container.Tab
        Preview                 matlab.ui.control.UIAxes
        ConfigurarBinarizaoTab  matlab.ui.container.Tab
        LedVerde                matlab.ui.control.Button
        LedAzul                 matlab.ui.control.Button
        LedVerm                 matlab.ui.control.Button
        LedBranco               matlab.ui.control.Button
        BinVerde                matlab.ui.control.NumericEditField
        ValordeBinarizaoLabel   matlab.ui.control.Label
        BinAzul                 matlab.ui.control.NumericEditField
        BinVerm                 matlab.ui.control.NumericEditField
        BinBranco               matlab.ui.control.NumericEditField
        BinSave                 matlab.ui.control.Button
        LedOff                  matlab.ui.control.Button
        BinOff                  matlab.ui.control.NumericEditField
        BinarizaPlot            matlab.ui.control.UIAxes
        ConfigurarframeTab      matlab.ui.container.Tab
        LEDONLabel              matlab.ui.control.Label
        NumF1                   matlab.ui.control.Spinner
        LEDOFFLabel             matlab.ui.control.Label
        NumF2                   matlab.ui.control.Spinner
        ProcFrame               matlab.ui.control.Button
        PlotOn                  matlab.ui.control.UIAxes
        PlotOff                 matlab.ui.control.UIAxes
        PlotDeResultadoTab      matlab.ui.container.Tab
        PlotResult              matlab.ui.control.UIAxes
        PesquisaFrameTab        matlab.ui.container.Tab
        Label                   matlab.ui.control.Label
        ExibirFrame             matlab.ui.control.Spinner
        Procurar                matlab.ui.control.Button
        Binary                  matlab.ui.control.UIAxes
        Circ                    matlab.ui.control.UIAxes
        Morph                   matlab.ui.control.UIAxes
        Gaussian                matlab.ui.control.UIAxes
        SelectLED               matlab.ui.container.ButtonGroup
        Verde                   matlab.ui.control.RadioButton
        Azul                    matlab.ui.control.RadioButton
        Vermelho                matlab.ui.control.RadioButton
        Branco                  matlab.ui.control.RadioButton
        Processar               matlab.ui.control.Button
        NomeVdeoLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        cam; % Description
        fps = 30;           %inicializa a captura de frames em 15 fps
        Imd = 'Imagem';      %Nome padrão para Imagem
        Vid = 'video';       %Nome padrão para Video
        Dir = 'C:\Users\GHILHERME\Desktop\Teste';
        LedUsado = 'Verde'
        im
        url ='https://esp32leddatabase.000webhostapp.com/esp32_update.php';
        options = weboptions('ContentType','text','Timeout',10000);
        Body = ["toggle_LED="];
        
        MedFrVerde;
        NumFrVerde;
        InvFrVerde;
        
        MedFrVerm;
        NumFrVerm;
        InvFrVerm;

        MedFrAzul;
        NumFrAzul;
        InvFrAzul;

        MedFrBranco;
        NumFrBranco;
        InvFrBranco;

        MedFrOff;
        NumFrOff;
        InvFrOff;

    end
    
    methods (Access = private)


        function app = LedSelect(app)
            
                if app.Verde.Value == 1
                    app.LedUsado = '_Verde_';
                    %app.Ledstatus.Value = app.LedUsado;
                end
                if app.Azul.Value == 1
                    app.LedUsado = '_Azul_';
                    %app.Ledstatus.Value = app.LedUsado;
                end
                if app.Vermelho.Value ==1
                    app.LedUsado = '_Vermelho_';
                    %app.Ledstatus.Value = app.LedUsado;
                end
                if app.Branco.Value ==1
                    app.LedUsado = '_Branco_';
                    %app.Ledstatus.Value = app.LedUsado;
                end            
          
            
        end
        
        function app = CamSelect(app)
           %Alterna entre a câmera integrada e a IP Cam
           %Para a IP Cam deve ser fornecido o endereço de ip                     
           if app.IPCamSelect.Value == 1
               %app.cam = ipcam(app.Endereco.Value)
               app.cam = webcam;
               app.Status.Value = 'ipcamera selec';
               
           end

           if app.IntegradaSelect.Value == 1
              app.cam = webcam;
              app.Status.Value = 'webcam select';
           end          
        end
        
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Preview.Visible = 'off';                    %deixa o preview desabilitado inicialmente
            app.Capturar.Enable = 'off';                   %deixa o botão de captura desab. enquanto estiver sem câmera
            app.Status.Value = 'Aguardando instruções' ;
            app.Processar.Enable = 'off';                  %desabilita o processamento enquanto não houver captura
            app.Endereco.Value = 'http:\\192.168.0.19';
            %app = CamSelect(app);
            app.SelectCam.Enable = 'off';                   %Inicializa com Seleção de camera desabilitada
            app.SelectLED.Enable = 'off';                   %Inicializa com Seleção de Led desabilitada
            app.SelectCam.SelectedObject = app.IntegradaSelect;
            %app.cam = webcam            
            app.url ='https://esp32leddatabase.000webhostapp.com/esp32_update.php';
            app.options = weboptions('ContentType','text','Timeout',10000);
            app.Body = "toggle_LED=";
            app.BinSave.Text = 'Desabilitar';
             
          
        end

        % Button pushed function: Inicializar
        function InicializarPushed(app, event)
            app.Status.Value= 'Inicializando câmera';
            app.SelectCam.Enable='on';
            app.SelectLED.Enable = 'on';
            app.Capturar.Enable="on";
            app.cam = webcam; %quando utilizando uma câmera USB (câmera do notebook)
            app=CamSelect(app)
            %cam = ipcam; %quanto utilizando a câmera ESP32
            frame = snapshot(app.cam); % 
            app.im = image(app.Preview, zeros(size(frame),'uint8'));
            axis(app.Preview,'image');
            preview(app.cam, app.im);
            app.Status.Value= 'Câmera Inicializada';  
            app.Capturar.Visible="on";

        end

        % Button pushed function: Capturar
        function CapturarPushed(app, event)
            app.Dir = uigetdir;
            app.SelectLED.Enable = 'off';                                   %desabilita a seleção de LED enquanto a gravação é feita
            app.SelectCam.Enable = 'off ';                                   %desabilita a seleção de câmera enquanto a gravação é feita
            app.Status.Value = ['Realizando Gravação led: ' app.LedUsado];
            %Body = ['toggle_LED:'+ app.LedUsado]
            t1 = 14;
            aux = [app.Dir '\' app.NomeVid.Value '_' app.LedUsado '.mp4' ];

            vidWriter = VideoWriter(aux,'Motion JPEG AVI');
            numFrames1 = 25;
            open(vidWriter)
            aux2 = [app.Dir '\' app.NomeIm.Value app.LedUsado];
            for index = 1:numFrames1
                Im = snapshot(app.cam);
                writeVideo(vidWriter,Im);
                imwrite(Im,[aux2, int2str(index), '.jpg']);
                if index == 75
                    % webwrite(url, Body, options);
                end                
       
            end
            close(vidWriter);
            app.Status.Value = 'Gravação finalizada' ;
            app.SelectLED.Enable = 'on';                                    %habilita a seleção de LED depois que a gravação é feita
            app.SelectCam.Enable = 'on';                                     %habilita a seleção de câmera depois que a gravação é feita  
            app.Processar.Enable = 'on';
            
            clear
        end

        % Callback function
        function TabSizeChanged(app, event)
            %position = app.PreviewTab.Position;
            app.Info.Children = app.Preview;
        end

        % Callback function
        function DiretorioButtonPushed(app, event)
            
        end

        % Selection changed function: SelectCam
        function SelectCamSelectionChanged(app, event)
          %Atualiza a camera utilizada no app
            app = CamSelect(app);
            
            
        end

        % Selection changed function: SelectLED
        function SelectLEDSelectionChanged(app, event)
            app = LedSelect(app);
            %app.Status.Value = app.LedUsado


        end

        % Button pushed function: LedVerde
        function LedVerdeButtonPushed(app, event)
           
%             ledNum= 1;
%             app.Body = "toggle_LED=" + ledNum;
%             webwrite(app.url, app.Body, app.options);
            pause(2);
            app.Status.Value= 'Configurando binarização';
            [filename, pathname] = uigetfile('*.*', 'pick');
            filename = strcat(pathname,filename);
                          
            app.im = imread(filename);                      %le o arquivo como imagem
            Image = rgb2gray(app.im);
            h= fspecial('gaussian');
            i2=imfilter(Image,h);
                %binarização
            
            i3= imbinarize(i2,app.BinVerde.Value);
                %filtragem morfológica
            se = strel('sphere',15);
            i4 = imerode(i3,se);
            i5 = imdilate(i4,se);                                            
            imshow(i5,'Parent',app.BinarizaPlot);
            app.Status.Value= 'Binarização concluida';
            
        end

        % Button pushed function: LedAzul
        function LedAzulButtonPushed(app, event)
            %ledNum= 2;
            %Body = "toggle_LED=" + ledNum;
            %webwrite(url, Body, options);
            pause(2);
            app.Status.Value= 'Configurando binarização';
            [filename, pathname] = uigetfile('*.*', 'pick');
            filename = strcat(pathname,filename);
                          
            app.im = imread(filename);                     %le o arquivo como imagem
            Image = rgb2gray(app.im);
            h= fspecial('gaussian');
            i2=imfilter(Image,h);
                %binarização
            
            i3= imbinarize(i2,app.BinAzul.Value);
                %filtragem morfológica
            se = strel('sphere',15);
            i4 = imerode(i3,se);
            i5 = imdilate(i4,se);                                            
            imshow(i5,'Parent',app.BinarizaPlot);
            app.Status.Value= 'Binarização concluida';
        end

        % Button pushed function: LedVerm
        function LedVermButtonPushed(app, event)
            %ledNum= 3;
%             Body = "toggle_LED=" + ledNum;
%             webwrite(url, Body, options);
            pause(2);
            app.Status.Value= 'Configurando binarização';
            [filename, pathname] = uigetfile('*.*', 'pick');
            filename = strcat(pathname,filename);
                          
            app.im = imread(filename);                      %le o arquivo como imagem
            Image = rgb2gray(app.im);
            h= fspecial('gaussian');
            i2=imfilter(Image,h);
                %binarização
            
            i3= imbinarize(i2,app.BinVerm.Value);
                %filtragem morfológica
            se = strel('sphere',15);
            i4 = imerode(i3,se);
            i5 = imdilate(i4,se);                                            
            imshow(i5,'Parent',app.BinarizaPlot);
            app.Status.Value= 'Binarização concluida';
        end

        % Button pushed function: LedBranco
        function LedBrancoButtonPushed(app, event)
            %ledNum= 4;
            %Body = "toggle_LED=" + int2str(ledNum);
            %webwrite(app.url, app.Body, app.options);
            pause(2);
            app.Status.Value= 'Configurando binarização';
            [filename, pathname] = uigetfile('*.*', 'pick');            
            filename = strcat(pathname,filename) ;                         
            app.im = imread(filename) ;                     %le o arquivo como imagem
            Image = rgb2gray(app.im);
            h= fspecial('gaussian');
            i2=imfilter(Image,h);
                %binarização
                
            i3= imbinarize(i2,app.BinBranco.Value);
                %filtragem morfológica
            se = strel('sphere',15);
            i4 = imerode(i3,se);
            i5 = imdilate(i4,se);                                            
            imshow(i5,'Parent',app.BinarizaPlot);
            app.Status.Value= 'Binarização concluida';
                
            
        end

        % Button pushed function: BinSave
        function BinSaveButtonPushed(app, event)
            switch(app.BinSave.Text)
                case 'Desabilitar'
                    app.BinAzul.Enable = 'off';
                    app.BinVerde.Enable = 'off';
                    app.BinVerm.Enable = 'off';
                    app.BinBranco.Enable = 'off';
                    app.BinOff.Enable = 'off';
                    app.LedAzul.Enable = 'off';
                    app.LedVerde.Enable = 'off';
                    app.LedVerm.Enable = 'off';
                    app.LedBranco.Enable = 'off';
                    app.LedOff.Enable = 'off';
                    app.BinSave.Text = 'Habilitar';
                case 'Habilitar'
                    app.BinAzul.Enable = 'on';
                    app.BinVerde.Enable = 'on';
                    app.BinVerm.Enable = 'on';
                    app.BinBranco.Enable = 'on';
                    app.BinOff.Enable = 'on';
                    app.LedAzul.Enable = 'on';
                    app.LedVerde.Enable = 'on';
                    app.LedVerm.Enable = 'on';
                    app.LedBranco.Enable = 'on';
                    app.LedOff.Enable = 'on';
                    app.BinSave.Text = 'Desabilitar';
            end
                    
        end

        % Button pushed function: Processar
        function ProcessarButtonPushed(app, event)
            aux1 = uigetdir;
            addpath(aux1);
            app.SelectLED.Enable = 'off';                                   %desabilita a seleção de LED enquanto a gravação é feita
            app.SelectCam.Enable = 'off ';                                   %desabilita a seleção de câmera enquanto a gravação é feita
            app.Capturar.Enable = 'off';
            app.Processar.Enable = 'off';
            t1 = 14;
            numFrames1 = t1*app.fps;
            for n=1:25
                addpath(aux1);
                app.Status.Value = ['Processando frame: ' int2str(n)];
            aux2= [app.NomeIm.Value app.LedUsado int2str(n) '.jpg'];
            IM = imread(sprintf(aux2)); %Leitura dos arquivos de imagem
            i1=rgb2gray(IM);    %converter para grayscale
            [lin,col]=size(i1);  %captura tamanho da imagem
            %Filtragens
            %Gaussiana
            h=fspecial('gaussian');
            i2=imfilter(i1,h);
            imwrite(i2,[aux1 '\gaussian' app.LedUsado int2str(n), '.jpg']); %salvar imagem vermelho
            %binarização

            if app.Verde.Value==1
                lvl = app.BinVerde.Value;
            end
            
            if app.Vermelho.Value==1
                lvl = app.BinVerm.Value;
            end

            if app.Azul.Value==1
                lvl = app.BinAzul.Value;
            end
            
            if app.Branco.Value==1
                lvl = app.BinBranco.Value;
            else
                lvl = app.BinOff.Value;
            end            


%             if LedStatus(n)==1 
%                lvl = lvlvermelho;
%             else
%                 lvl = lvlescuro;
%             end

            i3= imbinarize(i2,lvl);
            imwrite(i3,[aux1 '\binary' app.LedUsado int2str(n), '.jpg']);
            %filtragem morfológica
            se = strel('sphere',15);
            i4 = imerode(i3,se);
            i5 = imdilate(i4,se);
            imwrite(i5,[aux1 '\morph' app.LedUsado int2str(n), '.jpg']);
            figure(1)
            imshow(i5)
            %descritor de circularidade e fitting circular
            i6= imcomplement(i5);  %inverte pretro e branco
            status = regionprops('table',i6,'centroid','MajorAxisLength','MinorAxisLength','circularity','perimeter');
            centro = status.Centroid;
            diametro = mean([status.MajorAxisLength status.MinorAxisLength],2);
            raio = diametro/2;
            perimetro = status.Perimeter;
            circularidade = status.Circularity;
            imwrite(i6,[aux1 '\complet' app.LedUsado int2str(2),'.jpg']);
            figure(2)
            imshow(i6)
            
            viscircles(centro,raio)
            savefig([aux1 '\circ' app.LedUsado int2str(n), '.fig']); %cria o .fig
            fig=openfig([aux1 '\circ' app.LedUsado int2str(n), '.fig']); %openfig o .jpg
            saveas(fig,[aux1 '\circ' app.LedUsado int2str(n), '.jpg'],'jpg') %cria o .jpg
            close(fig);
            circularidade = circularidade(circularidade~=inf);
            diametro=max(diametro);
            ncirc=length(circularidade);
            
            if ncirc <= 1
                if circularidade >= 0.7
                    %medição pixels
                    MedicaoFrame(n) = diametro;
                    NumerodoFrame(n) = n;
                    invalido(n) = 0;
                    %med = regionprops('table',i7,'MaxIntensity','MeanIntensity','MinIntensity','PixelValues','WeightedCentroid');
                else
                    MedicaoFrame(n) = 0;
                    NumerodoFrame(n) = n;
                    invalido(n) = 1;
                end
            else
                MedicaoFrame(n) = 0;
                NumerodoFrame(n) = n;
                invalido(n) = 1;
            end


%             MedicaoFrame = cell2mat(MedicaoFrame);
%             invalido = cell2mat(invalido);
%             NumerodoFrame = cell2mat(NumerodoFrame);

            end
            if app.Verde.Value==1
                app.MedFrVerde = MedicaoFrame;
                app.NumFrVerde = NumerodoFrame;
                app.InvFrVerde = invalido;
            end
            
            if app.Vermelho.Value==1
                app.MedFrVerm = MedicaoFrame;
                app.NumFrVerm = NumerodoFrame;
                app.InvFrVerm = invalido;
            end

            if app.Azul.Value==1
              app.MedFrAzul = MedicaoFrame;
                app.NumFrAzul = NumerodoFrame;
                app.InvFrAzul = invalido;
            end
            
            if app.Branco.Value==1
               app.MedFrBranco = MedicaoFrame;
                app.NumFrBranco = NumerodoFrame;
                app.InvFrBranco = invalido;
            else
                app.MedFrOff = MedicaoFrame;
                app.NumFrOff = NumerodoFrame;
                app.InvFrOff = invalido;
            end  
            app.Status.Value = ['Processamento concluído: ' app.LedUsado];
            app.SelectLED.Enable = 'on';                                    %desabilita a seleção de LED enquanto a gravação é feita
            app.SelectCam.Enable = 'on ';                                   %desabilita a seleção de câmera enquanto a gravação é feita
            app.Capturar.Enable = 'on';
            app.Processar.Enable = 'on';
    
        end

        % Button pushed function: ProcFrame
        function ProcFrameButtonPushed(app, event)
            
            aux1 = uigetdir;
            addpath(aux1)
            aux2 = [app.NomeIm.Value app.LedUsado int2str(app.NumF1.Value) '.jpg'];
            im1= imread(sprintf(aux2));
            aux3 = [app.NomeIm.Value app.LedUsado int2str(app.NumF2.Value) '.jpg'];
            im2= imread(sprintf(aux3));
            
            %im1 = imread(sprintf('%d.jpg',aux,app.NomeIm.Value, app.LedUsado,int2str( app.NumF1.Value))); %imagem onde o led ascendeu
            %im2 = imread(sprintf('%d.jpg',aux,app.NomeIm.Value, app.LedUsado,int2str(app.NumF2.Value))); %imagem onde o led apagou
            imshow(im1,'Parent',app.PlotOff);   %mostrado no appdesign
            imshow(im2,'Parent',app.PlotOn);   %mostrado no appdesign
            %os valores dos frames devem ser configurados pelo usuário ao ajustar
            %visualmente onde o led apaga e ascende
            %botao confirma
%             LedStatus = zeros([1 350]);
%             for n=frame1:frame2
%                 LedStatus(n) = 1;
%             end
        end

        % Button pushed function: Procurar
        function ProcurarButtonPushed(app, event)
            aux1 = uigetdir;
            addpath(aux1)
            aux2 = ['gaussian' app.LedUsado  int2str(app.ExibirFrame.Value) '.jpg'];
            app.Status.Value = aux2
            im1= imread(sprintf(aux2));
            imshow(im1,'Parent',app.Gaussian);


            aux3 = ['morph'  app.LedUsado int2str(app.ExibirFrame.Value) '.jpg'];
            app.Status.Value = aux3;
            im2= imread(sprintf(aux3));
            imshow(im2,'Parent',app.Morph);

            aux4 = ['circ'  app.LedUsado int2str(app.ExibirFrame.Value) '.jpg'];
            im3= imread(sprintf(aux4));
            imshow(im3,'Parent',app.Circ);

            aux5 = ['binary'  app.LedUsado int2str(app.ExibirFrame.Value) '.jpg'];
            im3= imread(sprintf(aux5));
            imshow(im3,'Parent',app.Binary);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Inicializar = uibutton(app.UIFigure, 'push');
            app.Inicializar.ButtonPushedFcn = createCallbackFcn(app, @InicializarPushed, true);
            app.Inicializar.Text = 'Inicializar';
            app.StatusEditFieldLabel = uilabel(app.UIFigure);
            app.StatusEditFieldLabel.Text = 'Status:';
            app.Status = uieditfield(app.UIFigure, 'text');
            app.Capturar = uibutton(app.UIFigure, 'push');
            app.Capturar.ButtonPushedFcn = createCallbackFcn(app, @CapturarPushed, true);
            app.Capturar.Text = 'Capturar';
            app.NomeImagemLabel = uilabel(app.UIFigure);
            app.NomeImagemLabel.Text = 'Nome Imagem:';
            app.NomeIm = uieditfield(app.UIFigure, 'text');
            app.NomeIm.Value = 'imagem';
            app.SelectCam = uibuttongroup(app.UIFigure);
            app.SelectCam.SelectionChangedFcn = createCallbackFcn(app, @SelectCamSelectionChanged, true);
            app.SelectCam.Title = 'Seleção de Câmera';
            app.IPCamSelect = uiradiobutton(app.SelectCam);
            app.IPCamSelect.Text = 'IP Cam';
            app.IPCamSelect.Value = true;
            app.IntegradaSelect = uiradiobutton(app.SelectCam);
            app.IntegradaSelect.Text = 'Câmera integrada';
            app.Endereco = uieditfield(app.SelectCam, 'text');
            app.NomeVid = uieditfield(app.UIFigure, 'text');
            app.NomeVid.Value = 'Video';
            app.Info = uitabgroup(app.UIFigure);
            app.PreviewTab = uitab(app.Info);
            app.PreviewTab.Title = 'Preview';
            app.Preview = uiaxes(app.PreviewTab);
            title(app.Preview, {'Preview da Câmera'; ''})
            app.Preview.XLim = [0 1];
            app.Preview.XTick = [];
            app.Preview.YTick = [];
            app.ConfigurarBinarizaoTab = uitab(app.Info);
            app.ConfigurarBinarizaoTab.Title = 'Configurar Binarização';
            app.LedVerde = uibutton(app.ConfigurarBinarizaoTab, 'push');
            app.LedVerde.ButtonPushedFcn = createCallbackFcn(app, @LedVerdeButtonPushed, true);
            app.LedVerde.Text = 'LED Verde';
            app.LedAzul = uibutton(app.ConfigurarBinarizaoTab, 'push');
            app.LedAzul.ButtonPushedFcn = createCallbackFcn(app, @LedAzulButtonPushed, true);
            app.LedAzul.Text = 'LED Azul';
            app.LedVerm = uibutton(app.ConfigurarBinarizaoTab, 'push');
            app.LedVerm.ButtonPushedFcn = createCallbackFcn(app, @LedVermButtonPushed, true);
            app.LedVerm.Text = 'LED Vermelho';
            app.LedBranco = uibutton(app.ConfigurarBinarizaoTab, 'push');
            app.LedBranco.ButtonPushedFcn = createCallbackFcn(app, @LedBrancoButtonPushed, true);
            app.LedBranco.Text = 'LED Branco';
            app.BinVerde = uieditfield(app.ConfigurarBinarizaoTab, 'numeric');
            app.BinVerde.Limits = [0 1];
            app.BinVerde.Value = 0.7;
            app.ValordeBinarizaoLabel = uilabel(app.ConfigurarBinarizaoTab);
            app.ValordeBinarizaoLabel.Text = 'Valor de Binarização';
            app.BinAzul = uieditfield(app.ConfigurarBinarizaoTab, 'numeric');
            app.BinAzul.Limits = [0 1];
            app.BinAzul.Value = 0.7;
            app.BinVerm = uieditfield(app.ConfigurarBinarizaoTab, 'numeric');
            app.BinVerm.Limits = [0 1];
            app.BinVerm.Value = 0.7;
            app.BinBranco = uieditfield(app.ConfigurarBinarizaoTab, 'numeric');
            app.BinBranco.Limits = [0 1];
            app.BinBranco.Value = 0.7;
            app.BinSave = uibutton(app.ConfigurarBinarizaoTab, 'push');
            app.BinSave.ButtonPushedFcn = createCallbackFcn(app, @BinSaveButtonPushed, true);
            app.BinSave.Text = 'Habilitar';
            app.LedOff = uibutton(app.ConfigurarBinarizaoTab, 'push');
            app.LedOff.Text = 'LED Branco';
            app.BinOff = uieditfield(app.ConfigurarBinarizaoTab, 'numeric');
            app.BinOff.Limits = [0 1];
            app.BinOff.Value = 0.7;
            app.BinarizaPlot = uiaxes(app.ConfigurarBinarizaoTab);
            title(app.BinarizaPlot, 'Title')
            xlabel(app.BinarizaPlot, 'X')
            ylabel(app.BinarizaPlot, 'Y')
            zlabel(app.BinarizaPlot, 'Z')
            app.BinarizaPlot.XTick = [];
            app.BinarizaPlot.YTick = [];
            app.ConfigurarframeTab = uitab(app.Info);
            app.ConfigurarframeTab.Title = 'Configurar frame';
            app.LEDONLabel = uilabel(app.ConfigurarframeTab);
            app.LEDONLabel.Text = 'LED ON';
            app.NumF1 = uispinner(app.ConfigurarframeTab);
            app.NumF1.Limits = [1 350];
            app.NumF1.Value = 1;
            app.LEDOFFLabel = uilabel(app.ConfigurarframeTab);
            app.LEDOFFLabel.Text = 'LED OFF';
            app.NumF2 = uispinner(app.ConfigurarframeTab);
            app.NumF2.Limits = [1 350];
            app.NumF2.Value = 1;
            app.ProcFrame = uibutton(app.ConfigurarframeTab, 'push');
            app.ProcFrame.ButtonPushedFcn = createCallbackFcn(app, @ProcFrameButtonPushed, true);
            app.ProcFrame.Text = 'Procurar Frame';
            app.PlotOn = uiaxes(app.ConfigurarframeTab);
            title(app.PlotOn, 'Title')
            xlabel(app.PlotOn, 'X')
            ylabel(app.PlotOn, 'Y')
            zlabel(app.PlotOn, 'Z')
            app.PlotOn.XTick = [];
            app.PlotOn.YTick = [];
            app.PlotOff = uiaxes(app.ConfigurarframeTab);
            title(app.PlotOff, 'Title')
            xlabel(app.PlotOff, 'X')
            ylabel(app.PlotOff, 'Y')
            zlabel(app.PlotOff, 'Z')
            app.PlotOff.XTick = [];
            app.PlotOff.YTick = [];
            app.PlotDeResultadoTab = uitab(app.Info);
            app.PlotDeResultadoTab.Title = 'Resultado da Medição';
            app.PlotResult = uiaxes(app.PlotDeResultadoTab);
            title(app.PlotResult, 'Title')
            xlabel(app.PlotResult, 'X')
            ylabel(app.PlotResult, 'Y')
            zlabel(app.PlotResult, 'Z')
            app.PlotResult.XTick = [];
            app.PlotResult.XTickLabel = '';
            app.PlotResult.YTick = [];
            app.PlotResult.YTickLabel = '';
            app.PesquisaFrameTab = uitab(app.Info);
            app.PesquisaFrameTab.Title = 'Pesquisa frame';
            app.Label = uilabel(app.PesquisaFrameTab);
            app.Label.Text = '';
            app.ExibirFrame = uispinner(app.PesquisaFrameTab);
            app.ExibirFrame.Limits = [1 420];
            app.ExibirFrame.Value = 1;
            app.Procurar = uibutton(app.PesquisaFrameTab, 'push');
            app.Procurar.ButtonPushedFcn = createCallbackFcn(app, @ProcurarButtonPushed, true);
            app.Procurar.Text = 'Procurar';
            app.Binary = uiaxes(app.PesquisaFrameTab);
            title(app.Binary, 'Binarização')
            app.Binary.XTick = [];
            app.Binary.YTick = [];
            app.Circ = uiaxes(app.PesquisaFrameTab);
            title(app.Circ, 'Circularidade')
            app.Circ.XTick = [];
            app.Circ.YTick = [];
            app.Morph = uiaxes(app.PesquisaFrameTab);
            title(app.Morph, 'Morfologia')
            app.Morph.XTick = [];
            app.Morph.YTick = [];
            app.Gaussian = uiaxes(app.PesquisaFrameTab);
            title(app.Gaussian, 'Gaussian')
            app.Gaussian.XTick = [];
            app.Gaussian.YTick = [];
            app.SelectLED = uibuttongroup(app.UIFigure);
            app.SelectLED.SelectionChangedFcn = createCallbackFcn(app, @SelectLEDSelectionChanged, true);
            app.SelectLED.Title = 'Leds';
            app.Verde = uiradiobutton(app.SelectLED);
            app.Verde.Text = 'Verde';
            app.Verde.Value = true;
            app.Azul = uiradiobutton(app.SelectLED);
            app.Azul.Text = 'Azul';
            app.Vermelho = uiradiobutton(app.SelectLED);
            app.Vermelho.Text = 'Vermelho';
            app.Branco = uiradiobutton(app.SelectLED);
            app.Branco.Text = 'Branco';
            app.Processar = uibutton(app.UIFigure, 'push');
            app.Processar.ButtonPushedFcn = createCallbackFcn(app, @ProcessarButtonPushed, true);
            app.Processar.Text = 'Processar imagem';
            app.NomeVdeoLabel = uilabel(app.UIFigure);
            app.NomeVdeoLabel.Text = 'Nome Vídeo:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interface_V_6

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end